classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ver10Panel                  matlab.ui.container.Panel
        back_all                    matlab.ui.control.Button
        Panel                       matlab.ui.container.Panel
        TabGroup                    matlab.ui.container.TabGroup
        Tab                         matlab.ui.container.Tab
        Panel_4                     matlab.ui.container.Panel
        Button_rotate_shuzhi        matlab.ui.control.Button
        button_rotate_shuiping      matlab.ui.control.Button
        Button_image_rotate         matlab.ui.control.Button
        Panel_3                     matlab.ui.container.Panel
        variance                    matlab.ui.control.Slider
        Label_5                     matlab.ui.control.Label
        choosenoice                 matlab.ui.control.DropDown
        Label                       matlab.ui.control.Label
        Panel_2                     matlab.ui.container.Panel
        DropDown_choose_generate    matlab.ui.control.DropDown
        Label_20                    matlab.ui.control.Label
        Label_19                    matlab.ui.control.Label
        fake_rgb_generate           matlab.ui.control.Button
        doubleLabel                 matlab.ui.control.Label
        uint8Label                  matlab.ui.control.Label
        doubleButton                matlab.ui.control.Button
        uint8Button                 matlab.ui.control.Button
        button_2gray                matlab.ui.control.Button
        Label_6                     matlab.ui.control.Label
        rgbenhance                  matlab.ui.control.Button
        Label_3                     matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        HSIRGBButton                matlab.ui.control.Button
        RGBHSIButton                matlab.ui.control.Button
        Tab_2                       matlab.ui.container.Tab
        TabGroup3                   matlab.ui.container.TabGroup
        Tab_9                       matlab.ui.container.Tab
        button_generate_hist        matlab.ui.control.Button
        chk_stable_hist             matlab.ui.control.CheckBox
        line_gray                   matlab.ui.control.NumericEditField
        Label_12                    matlab.ui.control.Label
        UIAxes_hist                 matlab.ui.control.UIAxes
        Tab_10                      matlab.ui.container.Tab
        chk_change_center           matlab.ui.control.CheckBox
        Button_generate             matlab.ui.control.Button
        UIAxes_frequency            matlab.ui.control.UIAxes
        Panel_6                     matlab.ui.container.Panel
        TabGroup2                   matlab.ui.container.TabGroup
        Tab_5                       matlab.ui.container.Tab
        button_grayup               matlab.ui.control.Button
        chk_stable                  matlab.ui.control.CheckBox
        gamma                       matlab.ui.control.NumericEditField
        highout                     matlab.ui.control.NumericEditField
        Label_11                    matlab.ui.control.Label
        lowout                      matlab.ui.control.NumericEditField
        highin                      matlab.ui.control.NumericEditField
        Label_10                    matlab.ui.control.Label
        lowin                       matlab.ui.control.NumericEditField
        GammaLabel                  matlab.ui.control.Label
        Label_9                     matlab.ui.control.Label
        Label_8                     matlab.ui.control.Label
        Label_7                     matlab.ui.control.Label
        Tab_6                       matlab.ui.container.Tab
        hist_stable_enhance         matlab.ui.control.Button
        UIAxes_hist_new             matlab.ui.control.UIAxes
        Tab_7                       matlab.ui.container.Tab
        size_out                    matlab.ui.control.DropDown
        Label_15                    matlab.ui.control.Label
        boundary_size               matlab.ui.control.DropDown
        Label_14                    matlab.ui.control.Label
        start_filter                matlab.ui.control.Button
        fliter_w                    matlab.ui.control.DropDown
        Label_13                    matlab.ui.control.Label
        Tab_8                       matlab.ui.container.Tab
        enhance_fre                 matlab.ui.control.Button
        Slider_n                    matlab.ui.control.Slider
        Label_18                    matlab.ui.control.Label
        Slider_D0                   matlab.ui.control.Slider
        Label_17                    matlab.ui.control.Label
        DropDown_fliter_fre         matlab.ui.control.DropDown
        Label_16                    matlab.ui.control.Label
        Tab_11                      matlab.ui.container.Tab
        EditField_move              matlab.ui.control.NumericEditField
        Label_27                    matlab.ui.control.Label
        Button                      matlab.ui.control.Button
        Label_26                    matlab.ui.control.Label
        Slider_theta                matlab.ui.control.Slider
        Label_25                    matlab.ui.control.Label
        Tab_12                      matlab.ui.container.Tab
        EditField_therate           matlab.ui.control.NumericEditField
        Label_39                    matlab.ui.control.Label
        EditField_movexiangsudian   matlab.ui.control.NumericEditField
        Label_38                    matlab.ui.control.Label
        Label_37                    matlab.ui.control.Label
        Label_36                    matlab.ui.control.Label
        Slider_theta_weina          matlab.ui.control.Slider
        Label_35                    matlab.ui.control.Label
        EditField_lvbor             matlab.ui.control.EditField
        Label_34                    matlab.ui.control.Label
        Label_33                    matlab.ui.control.Label
        DropDown_chooseback_move_3  matlab.ui.control.DropDown
        Label_32                    matlab.ui.control.Label
        Button_moveback_2           matlab.ui.control.Button
        RadonRadonTab               matlab.ui.container.Tab
        DropDown_iradon_filter      matlab.ui.control.DropDown
        Label_41                    matlab.ui.control.Label
        DropDown_iradon_type        matlab.ui.control.DropDown
        Label_40                    matlab.ui.control.Label
        RadonButtonback             matlab.ui.control.Button
        RadonLabel_2                matlab.ui.control.Label
        RadonLabel                  matlab.ui.control.Label
        RadonButton                 matlab.ui.control.Button
        Tab_3                       matlab.ui.container.Tab
        Panel_10                    matlab.ui.container.Panel
        Label_44                    matlab.ui.control.Label
        TextArea2                   matlab.ui.control.TextArea
        TextArea_2Label             matlab.ui.control.Label
        Button_prediction           matlab.ui.control.Button
        Panel_7                     matlab.ui.container.Panel
        Label_43                    matlab.ui.control.Label
        TextArea                    matlab.ui.control.TextArea
        Label_42                    matlab.ui.control.Label
        button_huffman              matlab.ui.control.Button
        save_button                 matlab.ui.control.Button
        insert_button               matlab.ui.control.Button
        UIAxes_new                  matlab.ui.control.UIAxes
        UIAxes_original             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
    end
    properties (Access = public)
    % ... 其他属性 ...
    processedImage % 存储处理后的图像数据
    selectedMethod % 存储用户选择的处理方法
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: insert_button
        function insert_buttonButtonPushed(app, event)
            [Filename, FilePath] = uigetfile({'*.png;*.jpg', 'Image Files (*.png, *.jpg)'}, '请选择图片文件');
            if Filename ~= 0  % 确保用户没有取消选择
               full_filepath = fullfile(FilePath, Filename);  % 拼接文件路径和文件名
               img = imread(full_filepath);  % 读取图片
               imshow(img, 'Parent', app.UIAxes_original);  % 显示图片在指定的 UI 组件中
               axis(app.UIAxes_original, 'image');  % 使用图片的实际纵横比显示
               axis(app.UIAxes_new, 'image');  % 使用图片的实际纵横比显示
               imshow(img,'Parent',app.UIAxes_new);
            else
                disp('未选择文件');
            end
        end

        % Button pushed function: save_button
        function save_buttonButtonPushed(app, event)
             % 检查 UIAxes_new 是否有图像
             ax = app.UIAxes_new;
             if isempty(ax.Children) || ~any(strcmpi(class(ax.Children), 'matlab.graphics.primitive.Image'))
             % 如果没有图像，提示用户
                 uialert(app.UIFigure, '当前没有图像可以保存！', '保存失败', 'Icon', 'warning');
             return;
             end
    
    %因为所有图像操作必须是数值类型，所以说要获取图像对象。
            imgObj=findobj(ax,'Type','image');
            img=imgObj.CData;
    
    % 弹出文件保存对话框
    [file, path] = uiputfile({'*.png';'*.jpg';'*.bmp';'*.tiff'}, '保存图像');
    
    % 如果用户选择了文件路径，保存图像
    if file ~= 0
        imwrite(img, fullfile(path, file));
    end
        end

        % Button pushed function: button_2gray
        function button_2grayButtonPushed(app, event)
            img = app.UIAxes_new;
            if isempty(img.Children) || ~any(strcmpi(class(img.Children), 'matlab.graphics.primitive.Image'))
             % 如果没有图像，提示用户
                 uialert(app.UIFigure, '当前没有图像可以操作！', '操作失败', 'Icon', 'warning');
             return;
            end
            %因为所有图像操作必须是数值类型，所以说要获取图像对象。
            imgObj=findobj(img,'Type','image');
            img=imgObj.CData;

            img_new=im2gray(img);
            imshow(img_new, 'Parent', app.UIAxes_new);  % 显示图片在指定的 UI 组件中
        end

        % Button pushed function: back_all
        function back_allButtonPushed(app, event)
            %重置还原所有操作。
            img=app.UIAxes_original;
            if isempty(img.Children) || ~any(strcmpi(class(img.Children), 'matlab.graphics.primitive.Image'))
             % 如果没有图像，提示用户
                 uialert(app.UIFigure, '当前没有图像可以操作！', '操作失败', 'Icon', 'warning');
             return;
            end
            %把img转成数值类型。
            imgObj=findobj(img,'Type','image');
            img=imgObj.CData;
            
            imshow(img, 'Parent', app.UIAxes_new);  % 显示图片在指定的 UI 组件中
            axis(app.UIAxes_new, 'image');  % 使用图片的实际纵横比显示
        end

        % Value changing function: variance
        function varianceValueChanging(app, event)
            variable = event.Value;
            app.variance.Value = variable;
        end

        % Value changed function: choosenoice
        function choosenoiceValueChanged(app, event)
            noise_value = app.choosenoice.Value;
            img=app.UIAxes_original;
            if isempty(img.Children) || ~any(strcmpi(class(img.Children), 'matlab.graphics.primitive.Image'))
             % 如果没有图像，提示用户
                 uialert(app.UIFigure, '当前没有图像可以操作！', '操作失败', 'Icon', 'warning');
             return;
            end
            %把img转成数值类型。
            imgObj=findobj(img,'Type','image');
            img=imgObj.CData;

            %执行噪声修改
            if noise_value=="高斯噪声"
               img_new=imnoise(img,"gaussian",0,app.variance.Value);
            elseif noise_value=="椒盐噪声"
               img_new=imnoise(img,'salt & pepper',0.03);
            elseif noise_value=="泊松噪声"
                img_new=imnoise(img,"poisson");
            elseif noise_value=="斑点噪声"
                img_new=imnoise(img,"speckle",app.variance.Value);
            end

            %将噪声修改放入图片中。
           imshow(img_new, 'Parent', app.UIAxes_new);  % 显示图片在指定的 UI 组件中
            axis(app.UIAxes_new, 'image');  % 使用图片的实际纵横比显示
        end

        % Button pushed function: button_grayup
        function button_grayupButtonPushed(app, event)
            img=app.UIAxes_new;
            if isempty(img.Children) || ~any(strcmpi(class(img.Children), 'matlab.graphics.primitive.Image'))
             % 如果没有图像，提示用户
                 uialert(app.UIFigure, '当前没有图像可以操作！', '操作失败', 'Icon', 'warning');
             return;
            end
            %把img转成数值类型。
            imgObj=findobj(img,'Type','image');
            img=imgObj.CData;

            img=im2gray(img);

            if app.highout.Value>app.lowout.Value && app.highin.Value>app.lowin.Value
            elseif app.chk_stable.Value==true
            else
                uialert(app.UIFigure, '您的数值设置有问题！', '操作失败', 'Icon', 'warning');
             return;
            end
            if app.chk_stable.Value==true
                img_new=imadjust(img,stretchlim(img),[]);
            else
                img_new=imadjust(img,[app.lowin.Value,app.highin.Value],[app.lowout.Value,app.highout.Value],app.gamma.Value);
            end
             %将噪声修改放入图片中。
           imshow(img_new, 'Parent', app.UIAxes_new);  % 显示图片在指定的 UI 组件中
            axis(app.UIAxes_new, 'image');  % 使用图片的实际纵横比显示。
        end

        % Button pushed function: button_generate_hist
        function button_generate_histButtonPushed(app, event)
            img=app.UIAxes_original;
            if isempty(img.Children) || ~any(strcmpi(class(img.Children), 'matlab.graphics.primitive.Image'))
             % 如果没有图像，提示用户
                 uialert(app.UIFigure, '当前没有图像可以操作！', '操作失败', 'Icon', 'warning');
             return;
            end
            %把img转成数值类型。
            imgObj=findobj(img,'Type','image');
            img=imgObj.CData;
            
            img=im2gray(img);

            if app.chk_stable_hist.Value==true%如果勾选了归一化
                hist=imhist(img,app.line_gray.Value)/numel(img);
            else
                hist=imhist(img,app.line_gray.Value);
            end
            % 绘制直方图
           plot(app.UIAxes_hist, hist);
           title(app.UIAxes_hist,'灰度直方图');
           xlabel(app.UIAxes_hist, '灰度级');
           ylabel(app.UIAxes_hist, '频率');
        end

        % Button pushed function: hist_stable_enhance
        function hist_stable_enhanceButtonPushed(app, event)
            img=app.UIAxes_new;
            if isempty(img.Children) || ~any(strcmpi(class(img.Children), 'matlab.graphics.primitive.Image'))
             % 如果没有图像，提示用户
                 uialert(app.UIFigure, '当前没有图像可以操作！', '操作失败', 'Icon', 'warning');
             return;
            end
            %把img转成数值类型。
            imgObj=findobj(img,'Type','image');
            img=imgObj.CData;
            
            img=im2gray(img);

            %接下来开始直方图均衡化。
            [height,width] = size(img); 
            %% 统计每个灰度级的累积像素个数 
            N = imhist(img); 
            %% 统计各灰度级出现的频率 
            P = N./length(img(:)); 
            %% 计算累积频率 
            for i = 1:length(P) 
               Sp(i) = sum(P(1:i)); 
            end 
            Spn =uint8((256-1).*Sp); 
            %% 赋值 
            I_out = uint8(zeros(height,width)); 
            for i = 1:height 
               for j=1:width 
                   I_out(i,j) = Spn(img(i,j)+1); 
               end 
            end 
            N_out = imhist(I_out);

            %%接下来输出。
            imshow(I_out, 'Parent', app.UIAxes_new);  % 显示图片在指定的 UI 组件中
            axis(app.UIAxes_new, 'image');  % 使用图片的实际纵横比显示。
            plot(app.UIAxes_hist_new, N_out);%输出到直方图里
            title(app.UIAxes_hist,'增强后的灰度直方图');
           xlabel(app.UIAxes_hist, '灰度级');
           ylabel(app.UIAxes_hist, '频率');
        end

        % Button pushed function: RGBHSIButton
        function RGBHSIButtonPushed(app, event)
 % 从UIAxes中获取RGB图像
    % 假设app.UIAxes_new中显示的是RGB图像
    rgbImage = getimage(app.UIAxes_new);
    
    % 检查是否成功获取图像
    if isempty(rgbImage)
        uialert(app.UIFigure, '当前没有图像可以操作！', '操作失败', 'Icon', 'warning');
        return;
    end
    
    % 将图像转换为double类型
    rgb = im2double(rgbImage);
    
    % 提取RGB通道
    r = rgb(:,:,1);
    g = rgb(:,:,2);
    b = rgb(:,:,3);
    
    % 实现转换方程
    num = 0.5 * ((r - g) + (r - b));
    den = sqrt((r - g)^2 + (r - b) .* (g - b));
    theta = acos(num ./ (den + eps));
    
    H = theta;
    H(b > g) = 2 * pi - H(b > g);
    H = H / (2 * pi);
    
    num = min(min(r, g), b);
    den = r + g + b;
    den(den == 0) = eps;
    S = 1 - 3 * num ./ den;
    
    % 计算强度I
    I = (r + g + b) / 3;
    
    % 将H、S、I合并为HSI图像
    hsiImage = cat(3, H, S, I);
    
    % 显示HSI图像
    imshow(hsiImage, 'Parent', app.UIAxes_new); % 显示图片在指定的 UI 组件中
    axis(app.UIAxes_new, 'image'); % 使用图片的实际纵横比显示。
        end

        % Button pushed function: uint8Button
        function uint8ButtonPushed(app, event)
                % 第一步：从修改后的Axes中获取图像
    imgObj = findobj(app.UIAxes_new, 'Type', 'image');
    
    % 第二步：如果Axes没有图片，代表不能操作
    if isempty(imgObj)
        uialert(app.UIFigure, '当前没有图像可以操作！', '操作失败', 'Icon', 'warning');
        return;
    end
    
    % 第三步：将从Axes中获取的图像转换为矩阵形式
    img = imgObj.CData;
    
    % 检查图像数据类型，如果已经是uint8，则不需要转换
    if ~isa(img, 'uint8')
        % 第四步：将图像数据转换为uint8类型
        % 如果图像数据不是uint8类型，需要先归一化到[0, 1]，然后转换为uint8
        img = im2double(img);
        img_uint8 = im2uint8(img);
    else
        % 如果图像已经是uint8类型，直接使用
        img_uint8 = img;
    end
    
    % 第五步：显示转换后的uint8图像
    imshow(img_uint8, 'Parent', app.UIAxes_new);
        end

        % Button pushed function: doubleButton
        function doubleButtonPushed(app, event)
            % 第一步：从修改后的Axes中获取img
    % 注意：这里我们获取的是UIAxes_new中的图像对象，而不是UIAxes_new本身
    imgObj = findobj(app.UIAxes_new, 'Type', 'image');
    if isempty(imgObj)
        % 如果没有图像对象，提示用户
        uialert(app.UIFigure, '当前没有图像可以操作！', '操作失败', 'Icon', 'warning');
        return;
    end
    % 获取图像数据
    img = imgObj.CData;

    % 第二步：检查是否有图像数据
    if isempty(img)
        % 如果没有图像数据，提示用户
        uialert(app.UIFigure, '当前没有图像可以操作！', '操作失败', 'Icon', 'warning');
        return;
    end

    % 第三步：将从Axes中获取的img转换为矩阵形式
    % 由于img已经是图像数据，我们可以直接转换为double类型
    imgDouble = double(img);

    % 第四步：将转换后的图像显示在UIAxes_new中
    imshow(imgDouble, 'Parent', app.UIAxes_new); % 显示图片在指定的UI组件中
        end

        % Button pushed function: enhance_fre
        function enhance_freButtonPushed(app, event)
            img=app.UIAxes_original;
            if isempty(img.Children) || ~any(strcmpi(class(img.Children), 'matlab.graphics.primitive.Image'))
             % 如果没有图像，提示用户
                 uialert(app.UIFigure, '当前没有图像可以操作！', '操作失败', 'Icon', 'warning');
             return;
            end
            %把img转成数值类型。
            imgObj=findobj(img,'Type','image');
            img=imgObj.CData;

            img=rgb2gray(img);
            Fl=fft2(img);
            Fc=fftshift(Fl);
            [M,N]=size(img);
            center1=floor(M/2);
            center2=floor(N/2);
            D0=app.Slider_D0.Value;
            n=app.Slider_n.Value;
            H=zeros(M,N);

            for i=1:M
                for j=1:N
                    if app.DropDown_fliter_fre.Value=="理想低通滤波器"
                         %理想低通滤波器的情况。
                         d=sqrt((i-center1).^2+(j-center2).^2);
                          if d < D0
                              H(i,j)=1;
                          end
                    elseif app.DropDown_fliter_fre.Value=="ButterWorth低通滤波器"
                        %ButterWorth低通滤波器
                        d=sqrt((i-center1).^2+(j-center2).^2);
                        H(i,j)=1/(1+(d/D0).^(2*n));
                    elseif app.DropDown_fliter_fre.Value=="高斯低通滤波器"
                        %Gaussian低通滤波器
                        d=sqrt((i-center1).^2+(j-center2).^2);
                        H(i,j)=exp(-d.^2/(2*D0.^2));
                    elseif app.DropDown_fliter_fre.Value=="理想高通滤波器"
                        %理想高通滤波器。
                        d=sqrt((i-center1).^2+(j-center2).^2);
                          if d < D0
                              H(i,j)=0;
                          else
                              H(i,j)=1;
                          end
                    elseif app.DropDown_fliter_fre.Value=="ButterWorth高通滤波器"
                        %ButterWorth高通滤波器。
                        d=sqrt((i-center1).^2+(j-center2).^2);
                        H(i,j)=1/(1+(D0/d).^(2*n));
                    elseif app.DropDown_fliter_fre.Value=="高斯高通滤波器"
                        %高斯高通滤波器
                        d=sqrt((i-center1).^2+(j-center2).^2);
                        H(i,j)=1-exp(-d.^2/(2*D0.^2));
                    end
                end
            end
            %%频率域转化回来。
            Fh=Fc.*H;
            img_new=real(ifft2(ifftshift(Fh)));

            %%输出图像到axis_new上。
            imshow(img_new,[],'Parent', app.UIAxes_new);
            
            % 可视化滤波器
            
        end

        % Button pushed function: HSIRGBButton
        function HSIRGBButtonPushed(app, event)
             % 第一步：从UIAxes_new中获取HSI图像
    hsiImage =app.UIAxes_new;
    
    % 第二步：如果UIAxes_new没有图片，代表不能操作
     if isempty(hsiImage.Children) || ~any(strcmpi(class(hsiImage.Children), 'matlab.graphics.primitive.Image'))
             % 如果没有图像，提示用户
                 uialert(app.UIFigure, '当前没有图像可以操作！', '操作失败', 'Icon', 'warning');
             return;
     end
            %把img转成数值类型。
            imgObj=findobj(hsiImage,'Type','image');
            hsiImage=imgObj.CData;


    hsi = hsiImage;
    H = hsi(:,:,1) * 2 * pi;
    S = hsi(:,:,2);
    I = hsi(:,:,3);

    R=zeros(size(hsi,1),size(hsi,2));
    G=zeros(size(hsi,1),size(hsi,2));
    B=zeros(size(hsi,1),size(hsi,2));

    idx=find((0<=H)&(H<2*pi/3));
    B(idx)=I(idx).*(1-S(idx));
    R(idx)=I(idx).*(1+S(idx).*cos(H(idx))./cos(pi/3-H(idx)));
    G(idx)=3*I(idx)-(R(idx)+B(idx));

    idx=find((2*pi/3<=H)&(H<4*pi/3));
    R(idx)=I(idx).*(1-S(idx));
    G(idx)=I(idx).*(1+S(idx).*cos(H(idx)-2*pi/3)./cos(pi-H(idx)));
    B(idx)=3*I(idx)-(R(idx)+G(idx));

    idx=find((4*pi/3<=H)&(H<=2*pi));
    G(idx)=I(idx).*(1-S(idx));
    B(idx)=I(idx).*(1+S(idx).*cos(H(idx)-4*pi/3)./cos(5*pi/3-H(idx)));
    R(idx)=3*I(idx)-(G(idx)+B(idx));


    rgb=cat(3,R,G,B);
    rgb=max(min(rgb,1),0);


    imshow(rgb,[], 'Parent', app.UIAxes_new); % 显示图片在指定的 UI 组件中
    axis(app.UIAxes_new, 'image'); % 使用图片的实际纵横比显示
        end

        % Button pushed function: rgbenhance
        function rgbenhanceButtonPushed(app, event)
             % 第一步：从UIAxes_new中获取彩色图像
    originalImage = getimage(app.UIAxes_new);
    
    % 第二步：如果UIAxes_new没有图片，代表不能操作
    if isempty(originalImage)
        uialert(app.UIFigure, '当前没有图像可以操作！', '操作失败', 'Icon', 'warning');
        return;
    end
    
    % 第三步：将原始图像转换为double类型以进行处理
    rgb = im2double(originalImage);
    
    r = rgb(:, :, 1);  % 提取红色通道
    g = rgb(:, :, 2);  % 提取绿色通道
    b = rgb(:, :, 3);  % 提取蓝色通道

    % 第四步：定义平均滤波器
    m = fspecial('average', [8, 8]);  % 创建8x8的平均滤波器
    
    % 第五步：应用滤波器到每个颜色通道
    r_filtered = imfilter(r, m);
    g_filtered = imfilter(g, m);
    b_filtered = imfilter(b, m);
    
    rgb_filtered = cat(3, r_filtered, g_filtered, b_filtered);  % 合并通道
    
    % 第六步：显示平滑后的图像
    imshow(rgb_filtered, 'Parent', app.UIAxes_new); % 在同一个UI Axes 上显示图片
        end

        % Button pushed function: Button_generate
        function Button_generatePushed(app, event)
            img=app.UIAxes_original;
            if isempty(img.Children) || ~any(strcmpi(class(img.Children), 'matlab.graphics.primitive.Image'))
             % 如果没有图像，提示用户
                 uialert(app.UIFigure, '当前没有图像可以操作！', '操作失败', 'Icon', 'warning');
             return;
            end
            %把img转成数值类型。
            imgObj=findobj(img,'Type','image');
            img=imgObj.CData;
            
            img=im2gray(img);
            %生成频率域图片
            img=fft2(img);%进行傅里叶变换。
            if app.chk_change_center==true
                img=ifftshift(img);
            end
            S=abs(img);
            S = log(1 + S);
            %show一下：
            imshow(S,[],'Parent', app.UIAxes_frequency);
            %自适应：
            axis(app.UIAxes_frequency, 'image'); % 使用图片的实际纵横比显示。
        end

        % Button pushed function: start_filter
        function start_filterButtonPushed(app, event)
            img=app.UIAxes_new;
            if isempty(img.Children) || ~any(strcmpi(class(img.Children), 'matlab.graphics.primitive.Image'))
             % 如果没有图像，提示用户
                 uialert(app.UIFigure, '当前没有图像可以操作！', '操作失败', 'Icon', 'warning');
             return;
            end
            %把img转成数值类型。
            imgObj=findobj(img,'Type','image');
            img=imgObj.CData;

            img=im2gray(img);

                % 根据选择的滤波器类型进行滤波
            if(app.boundary_size.Value=="repilcate")
                boundary="replicate";
            end
        if(app.boundary_size.Value=="symmetric")
                boundary="symmetric";
        end
        if(app.boundary_size.Value=="circular")
                boundary="circular";
        end
        if(app.size_out.Value=="same")
            size="same";
        end
        if(app.size_out.Value=="conv")
            size="conv";
        end
        if(app.fliter_w.Value=="高斯滤波器")
        fliter="gaussian";
        w = fspecial(fliter);
        filtered_img = imfilter(img,w,boundary,size);
        end
        if(app.fliter_w.Value=="矩形平均滤波器")
        fliter= "average";
        w = fspecial(fliter);
        filtered_img = imfilter(img,w,boundary,size);
        end
        if(app.fliter_w.Value=="中值滤波器")
        img_new=im2gray(img);
        m = 3;
        n = 3;
        filtered_img = medfilt2(img_new,[m n],'zeros');
        end
        if(app.fliter_w.Value=="排序滤波器")
        img_new=im2gray(img);
        filtered_img = ordfilt2(img_new,1,ones(3,3));
        end


    % 显示结果
    imshow(filtered_img, 'Parent', app.UIAxes_new); % 显示图片在指定的 UI 组件中
    axis(app.UIAxes_new, 'image'); % 使用图片的实际纵横比显示
    
        end

        % Button pushed function: button_rotate_shuiping
        function button_rotate_shuipingPushed(app, event)
            img = app.UIAxes_new;
            if isempty(img.Children) || ~any(strcmpi(class(img.Children), 'matlab.graphics.primitive.Image'))
             % 如果没有图像，提示用户
                 uialert(app.UIFigure, '当前没有图像可以操作！', '操作失败', 'Icon', 'warning');
             return;
            end

            %把img转成数值类型。
            imgObj=findobj(img,'Type','image');
            img=imgObj.CData;

            % 水平翻转图像
            flipped_img = flip(img, 2);  % 2 表示水平翻转
            % 显示原图和旋转后的图像
            imshow(flipped_img, 'Parent', app.UIAxes_new);
        end

        % Button pushed function: Button_image_rotate
        function Button_image_rotatePushed(app, event)
            img = app.UIAxes_new;
            if isempty(img.Children) || ~any(strcmpi(class(img.Children), 'matlab.graphics.primitive.Image'))
             % 如果没有图像，提示用户
                 uialert(app.UIFigure, '当前没有图像可以操作！', '操作失败', 'Icon', 'warning');
             return;
            end

            %把img转成数值类型。
            imgObj=findobj(img,'Type','image');
            img=imgObj.CData;
            
            % 设置旋转角度
            theta = 90;  % 旋转90度

            % 使用imrotate函数旋转图像
            rotated_img = imrotate(img, theta);

            % 显示原图和旋转后的图像
            imshow(rotated_img, 'Parent', app.UIAxes_new);
        end

        % Button pushed function: Button_rotate_shuzhi
        function Button_rotate_shuzhiPushed(app, event)
             img = app.UIAxes_new;
            if isempty(img.Children) || ~any(strcmpi(class(img.Children), 'matlab.graphics.primitive.Image'))
             % 如果没有图像，提示用户
                 uialert(app.UIFigure, '当前没有图像可以操作！', '操作失败', 'Icon', 'warning');
             return;
            end

            %把img转成数值类型。
            imgObj=findobj(img,'Type','image');
            img=imgObj.CData;

            % 水平翻转图像
            flipped_img = flip(img, 1);  % 2 表示水平翻转
            % 显示原图和旋转后的图像
            imshow(flipped_img, 'Parent', app.UIAxes_new); 
            axis(app.UIAxes_new, 'image'); % 使用图片的实际纵横比显示
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %%生成运动模糊图像。
             img = app.UIAxes_new;
            if isempty(img.Children) || ~any(strcmpi(class(img.Children), 'matlab.graphics.primitive.Image'))
             % 如果没有图像，提示用户
                 uialert(app.UIFigure, '当前没有图像可以操作！', '操作失败', 'Icon', 'warning');
             return;
            end

            %把img转成数值类型。
            imgObj=findobj(img,'Type','image');
            img=imgObj.CData;
            img=im2gray(img);

            LEN=app.EditField_move.Value;
            THETA=app.Slider_theta.Value;
            PSF=fspecial('motion',LEN,THETA);

            img_new=imfilter(img,PSF,'circular','conv');

            imshow(img_new, 'Parent', app.UIAxes_new);
        end

        % Button pushed function: Button_moveback_2
        function Button_movebackPushed(app, event)
            img = app.UIAxes_new;
            if isempty(img.Children) || ~any(strcmpi(class(img.Children), 'matlab.graphics.primitive.Image'))
             % 如果没有图像，提示用户
                 uialert(app.UIFigure, '当前没有图像可以操作！', '操作失败', 'Icon', 'warning');
             return;
            end

            %把img转成数值类型。
            imgObj=findobj(img,'Type','image');
            img=imgObj.CData;
            img=im2gray(img);

            if app.DropDown_chooseback_move_3.Value=="逆滤波"
    %将图像进行double类型归一化
    image_d = im2double(img);
    %将图像从时域转到频域
    image_d = fftshift(fft2(image_d));
    %获取频域的尺寸
    [M, N] = size(image_d);
    %预存大气湍流模型的退化函数
    [u,v]=meshgrid(1:M,1:N);%生成二维坐标系
    H=exp(-0.0025* ( (u'-M/2).^2+(v'-N/2).^2).^(5/6) );
    %定义逆滤波的滤波半径
    threshold = app.EditField_lvbor.Value;
    if threshold > M / 2
        image_d = image_d / (H + eps);
    else 
        for i = 1 : M
            for j = 1 : N
                if sqrt((i - M / 2).^2 + (j - N / 2).^2) < threshold
                    image_d(i, j) = image_d(i, j) ./ (H(i, j) + eps);
                end
            end
        end
    end
    
    result_image = ifft2(ifftshift(image_d));
    result_image = uint8(abs(result_image)*255);
            elseif app.DropDown_chooseback_move_3.Value=="维纳滤波"
                LEN=app.EditField_movexiangsudian.Value;
                THETA=app.Slider_theta_weina.Value;
                PSF=fspecial('motion',LEN,THETA);
                rate=app.EditField_therate.Value;
                result_image=deconvwnr(img,PSF,rate);
            end
            imshow(result_image,[], 'Parent', app.UIAxes_new);
            axis(app.UIAxes_new, 'image'); % 使用图片的实际纵横比显示
        end

        % Button pushed function: RadonButton
        function RadonButtonPushed(app, event)
            %%生成运动模糊图像。
             img = app.UIAxes_new;
            if isempty(img.Children) || ~any(strcmpi(class(img.Children), 'matlab.graphics.primitive.Image'))
             % 如果没有图像，提示用户
                 uialert(app.UIFigure, '当前没有图像可以操作！', '操作失败', 'Icon', 'warning');
             return;
            end

            %把img转成数值类型。
            imgObj=findobj(img,'Type','image');
            img=imgObj.CData;

            img=im2gray(img);
            %%执行Radon变换
            theta=0:1:179;
            [R,xp]=radon(img,theta);
            imagesc(app.UIAxes_new,theta,xp,R);
            axis(app.UIAxes_new, 'image'); % 使用图片的实际纵横比显示
        end

        % Button pushed function: RadonButtonback
        function RadonButtonbackPushed(app, event)
            img = app.UIAxes_new;
            if isempty(img.Children) || ~any(strcmpi(class(img.Children), 'matlab.graphics.primitive.Image'))
             % 如果没有图像，提示用户
                 uialert(app.UIFigure, '当前没有图像可以操作！', '操作失败', 'Icon', 'warning');
             return;
            end

            %把img转成数值类型。
            imgObj=findobj(img,'Type','image');
            img=imgObj.CData;

            theta = 0:1:179;  % 假设的角度范围（需要根据实际情况调整）
            
            type=app.DropDown_iradon_type.Value;
            filter=app.DropDown_iradon_filter.Value;
% 执行反Radon变换（从投影数据img恢复图像）
reconstructed_img = iradon(img, theta, type, filter, 1, size(img,1)); 

% 在UI中显示重建后的图像
imshow(reconstructed_img, [], 'Parent', app.UIAxes_new);
        end

        % Drop down opening function: DropDown_choose_generate
        function DropDown_choose_generateOpening(app, event)
             app.DropDown_choose_generate.Items = {'密度分割法', '灰度级变换法', '频率滤波法'};
        end

        % Clicked callback: DropDown_choose_generate
        function DropDown_choose_generateClicked(app, event)
            
            item = event.InteractionInformation.Item;
    grayImage = getimage(app.UIAxes_new);
    if isempty(grayImage)
        uialert(app.UIFigure, '当前没有图像可以操作！', '操作失败', 'Icon', 'warning');
        return;
    end
    grayImage=im2gray(grayImage);
    grayImage = im2double(grayImage);
    app.selectedMethod = app.DropDown_choose_generate.Value; % 存储用户选择的处理方法
    
    switch app.selectedMethod
        case '密度分割法'
            % 密度分割法的实现
            threshold = graythresh(grayImage); % 自动计算阈值
            app.processedImage = imbinarize(grayImage, threshold);
        case '灰度级变换法'
            % 灰度级变换法的实现
            epsilon = 1e-10; % 防止 log(0)
            app.processedImage = log(1.0 + grayImage + epsilon);
            app.processedImage = mat2gray(app.processedImage);
        case '频率滤波法'
            % 频率滤波法的实现
            F = fft2(double(grayImage));
            F = fftshift(F);
            rows = size(grayImage, 1);
            cols = size(grayImage, 2);
            crow = round(rows/2);
            ccol = round(cols/2);
            [u, v] = meshgrid(-ccol:(cols-1-ccol), -crow:(rows-1-crow));
            D = sqrt(u.^2 + v.^2);
            H = double(D <= 30); % 半径为30的圆
            G = H.*F;
            G = ifftshift(G);
            app.processedImage = ifft2(G);
            app.processedImage = real(app.processedImage);
        otherwise
            uialert(app.UIFigure, '请选择一个有效的图像处理方法。', '错误', 'Icon', 'warning');
            return;
    end
        end

        % Button pushed function: fake_rgb_generate
        function fake_rgb_generateButtonPushed(app, event)
              % 如果已经处理了图像，再次显示它
    if ~isempty(app.processedImage)
        % 显示处理后的图像
        imshow(app.processedImage, 'Parent', app.UIAxes_new);
        
        % 应用 'jet' 颜色映射
        colormap(app.UIAxes_new, 'jet');
        
        % 设置颜色映射的数据范围
        caxis(app.UIAxes_new, [0 1]); % 假设处理后的图像数据在 [0, 1] 范围内
        
        title(app.UIAxes_new, ['处理后的图像 - ', app.selectedMethod]);
    else
        uialert(app.UIFigure, '请先选择一个图像处理方法。', '操作失败', 'Icon', 'warning');
    end
        end

        % Button pushed function: button_huffman
        function button_huffmanButtonPushed(app, event)
            img = app.UIAxes_new;
            if isempty(img.Children) || ~any(strcmpi(class(img.Children),'matlab.graphics.primitive.Image'))
                % 如果没有图像，提示用户
                uialert(app.UIFigure, '当前没有图像可以操作！', '操作失败', 'Icon','warning');
                return;
            end
            imgObj = findobj(img, 'Type', 'image');
            img = imgObj.CData;
            
            % 如果是彩色图像，分别处理 R、G、B 通道
            if size(img, 3) == 3
                % 分别提取三个通道
                R = img(:, :, 1);
                G = img(:, :, 2);
                B = img(:, :, 3);
                
                % 对每个通道进行霍夫曼编码
                [R_enc, R_dict] = huffman_encode(R);
                [G_enc, G_dict] = huffman_encode(G);
                [B_enc, B_dict] = huffman_encode(B);
                
                % 解码每个通道
                R_dec = huffmandeco(R_enc, R_dict);
                G_dec = huffmandeco(G_enc, G_dict);
                B_dec = huffmandeco(B_enc, B_dict);
                
                % 将解码后的通道重新组合
                decoded_img = cat(3, reshape(R_dec, size(R)), reshape(G_dec, size(G)), reshape(B_dec, size(B)));
                
                % 显示解码后的彩色图像
                imshow(uint8(decoded_img), 'Parent', app.UIAxes_new);
                
                % 计算原始图像和压缩图像的大小
                originalBits = numel(img) * 8;  % 原始图像大小(位)
                compressedBits = numel(R_enc) + numel(G_enc) + numel(B_enc);  % 压缩后图像的总位数
                compressionRatio = originalBits / compressedBits;
                
                % 显示原图、压缩图像大小和压缩比
                app.TextArea.Value = sprintf('原图: %d bit 压缩图像: %d bit 压缩比: %.2f', originalBits, compressedBits, compressionRatio);
            else
                % 如果是灰度图像，直接处理
                img_gray = rgb2gray(img);  % 将图像转为灰度图像
                [encoded, dict] = huffman_encode(img_gray);
                decoded = huffmandeco(encoded, dict);
                decoded_img = reshape(decoded, size(img_gray));
                
                % 显示解码后的图像
                imshow(uint8(decoded_img), 'Parent', app.UIAxes_new);
                
                % 计算原始和压缩图像大小
                originalBits = numel(img_gray) * 8;  % 原始图像大小(位)
                compressedBits = numel(encoded);  % 压缩后的大小(位)
                compressionRatio = originalBits / compressedBits;
                
                % 显示原图、压缩图像大小和压缩比
                app.TextArea.Value = sprintf('原图: %d bit 压缩图像: %d bit 压缩比: %.2f', originalBits, compressedBits, compressionRatio);
            end
            
            function [encoded, dict] = huffman_encode(img)
                img_vec = img(:);  % 将图像转换为一维向量
                P = histcounts(img_vec, 0:256) / numel(img_vec);  % 计算概率
                dict = huffmandict(0:255, P);  % 生成霍夫曼字典
                encoded = huffmanenco(img_vec, dict);  % 编码
            end
        end

        % Button pushed function: Button_prediction
        function Button_predictionPushed(app, event)
              function compressed_image = predict_compress_image(input_image)
            % 获取图像尺寸
            [height, width, channels] = size(input_image);
        
            % 分别存储每个通道的相关压缩信息
            compressed_image.channels = channels;
            compressed_image.channel_data = cell(channels, 1);
        
            % 对每个颜色通道分别进行处理
            for ch = 1:channels
                % 获取当前通道图像数据
                channel_image = input_image(:, :, ch);
        
                % 预测误差存储矩阵
                prediction_error = zeros(height, width);
                % 预测值矩阵
                predicted_values = zeros(height, width);
        
                % 简单的预测规则，这里采用前一个像素作为预测值（可以替换为更复杂的预测模型）
                for row = 1:height
                    for col = 1:width
                        if col == 1
                            % 边界情况，第一列像素设为自身值作为预测值
                            predicted_values(row, col) = channel_image(row, col);
                        else
                            % 用前一个像素预测当前像素
                            predicted_values(row, col) = predicted_values(row, col - 1);
                        end
                        % 计算预测误差
                        prediction_error(row, col) = channel_image(row, col) - predicted_values(row, col);
                    end
                end
        
                % 对预测误差进行量化（简单示例，实际可更精细量化）
                quantized_error = round(prediction_error / 8);  % 简单除以一个量化步长并取整
        
                % 将量化后的误差展平为一维向量用于霍夫曼编码
                quantized_error_vec = quantized_error(:);
        
                % 构建符号概率分布（统计每个量化值出现的频率）
                unique_vals = unique(quantized_error_vec);
                num_unique = numel(unique_vals);
                p = zeros(num_unique, 1);
                for i = 1:num_unique
                    p(i) = sum(quantized_error_vec == unique_vals(i)) / numel(quantized_error_vec);
                end
        
                % 进行霍夫曼编码
                dict = huffmandict(unique_vals, p);
                encoded_error = huffmanenco(quantized_error_vec, dict);
        
                % 将当前通道的霍夫曼编码后的结果、字典信息、图像尺寸、预测值等相关信息组合成结构体存储
                channel_data_struct = struct();
                channel_data_struct.encoded_error = encoded_error;
                channel_data_struct.dict = dict;
                channel_data_struct.height = height;
                channel_data_struct.width = width;
                channel_data_struct.predicted_values = predicted_values;
        
                % 将当前通道结构体存入总的通道数据单元格数组
                compressed_image.channel_data{ch} = channel_data_struct;
            end
        end
        
        % 图像解压函数
        function decompressed_image = predict_decompress_image(compressed_image)
            % 获取通道数
            channels = compressed_image.channels;
            % 初始化解压后的图像
            decompressed_image = zeros([compressed_image.channel_data{1}.height, compressed_image.channel_data{1}.width, channels]);
        
            % 对每个颜色通道分别进行解压恢复
            for ch = 1:channels
                % 获取当前通道的压缩数据结构体
                channel_data_struct = compressed_image.channel_data{ch};
        
                % 先进行霍夫曼解码
                decoded_error_vec = huffmandeco(channel_data_struct.encoded_error, channel_data_struct.dict);
        
                % 恢复预测误差（反量化）
                height = channel_data_struct.height;
                width = channel_data_struct.width;
                recovered_error = reshape(decoded_error_vec, [height, width]) * 8;
        
                % 恢复图像
                predicted_values = channel_data_struct.predicted_values;
                for row = 1:height
                    for col = 1:width
                        decompressed_image(row, col, ch) = predicted_values(row, col) + recovered_error(row, col);
                    end
                end
            end
        end
        img = app.UIAxes_new;
            if isempty(img.Children) || ~any(strcmpi(class(img.Children),'matlab.graphics.primitive.Image'))
                % 如果没有图像，提示用户
                uialert(app.UIFigure, '当前没有图像可以操作！', '操作失败', 'Icon','warning');
                return;
            end
            imgObj = findobj(img, 'Type', 'image');
            img = imgObj.CData;
        compressed_image = predict_compress_image(img);
        decompressed_image = predict_decompress_image(compressed_image);
         % 显示解码后的图像
        imshow(uint8(decompressed_image), 'Parent', app.UIAxes_new);
        % 计算原始和压缩图像大小
                originalBits = numel(img) * 8;  % 原始图像大小(位)
                compressedBits = numel(compressed_image);  % 压缩后的大小(位)
                compressionRatio = originalBits / compressedBits;
                
                % 显示原图、压缩图像大小和压缩比
                app.TextArea2.Value = sprintf('原图: %d bit 压缩图像: %d bit 压缩比: %.2f', originalBits, compressedBits, compressionRatio);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ver10Panel = uipanel(app.UIFigure);
            app.ver10Panel.Title = '欢迎来到图像处理程序ver1.0！';
            app.UIAxes_original = uiaxes(app.ver10Panel);
            title(app.UIAxes_original, '原图')
            xlabel(app.UIAxes_original, 'X')
            ylabel(app.UIAxes_original, 'Y')
            zlabel(app.UIAxes_original, 'Z')
            app.UIAxes_new = uiaxes(app.ver10Panel);
            title(app.UIAxes_new, '修改后的图片')
            xlabel(app.UIAxes_new, 'X')
            ylabel(app.UIAxes_new, 'Y')
            zlabel(app.UIAxes_new, 'Z')
            app.insert_button = uibutton(app.ver10Panel, 'push');
            app.insert_button.ButtonPushedFcn = createCallbackFcn(app, @insert_buttonButtonPushed, true);
            app.insert_button.Icon = fullfile(pathToMLAPP, 'icons', 'insert.png');
            app.insert_button.Text = '插入图片';
            app.save_button = uibutton(app.ver10Panel, 'push');
            app.save_button.ButtonPushedFcn = createCallbackFcn(app, @save_buttonButtonPushed, true);
            app.save_button.Icon = fullfile(pathToMLAPP, 'icons', 'save.png');
            app.save_button.Text = '保存图片';
            app.Panel = uipanel(app.ver10Panel);
            app.Panel.Title = '图片编辑器';
            app.TabGroup = uitabgroup(app.Panel);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '图像处理';
            app.Panel_2 = uipanel(app.Tab);
            app.Panel_2.Title = '图像处理';
            app.RGBHSIButton = uibutton(app.Panel_2, 'push');
            app.RGBHSIButton.ButtonPushedFcn = createCallbackFcn(app, @RGBHSIButtonPushed, true);
            app.RGBHSIButton.Text = 'RGB转HSI';
            app.HSIRGBButton = uibutton(app.Panel_2, 'push');
            app.HSIRGBButton.ButtonPushedFcn = createCallbackFcn(app, @HSIRGBButtonPushed, true);
            app.HSIRGBButton.Text = 'HSI转RGB';
            app.Label_2 = uilabel(app.Panel_2);
            app.Label_2.Text = '色彩转换模块';
            app.Label_3 = uilabel(app.Panel_2);
            app.Label_3.Text = '彩色图像平滑';
            app.rgbenhance = uibutton(app.Panel_2, 'push');
            app.rgbenhance.ButtonPushedFcn = createCallbackFcn(app, @rgbenhanceButtonPushed, true);
            app.rgbenhance.Text = '彩色图像平滑';
            app.Label_6 = uilabel(app.Panel_2);
            app.Label_6.Text = '生成灰度图像';
            app.button_2gray = uibutton(app.Panel_2, 'push');
            app.button_2gray.ButtonPushedFcn = createCallbackFcn(app, @button_2grayButtonPushed, true);
            app.button_2gray.Text = '灰度图像生成';
            app.uint8Button = uibutton(app.Panel_2, 'push');
            app.uint8Button.ButtonPushedFcn = createCallbackFcn(app, @uint8ButtonPushed, true);
            app.uint8Button.Text = 'uint8图像转换';
            app.doubleButton = uibutton(app.Panel_2, 'push');
            app.doubleButton.ButtonPushedFcn = createCallbackFcn(app, @doubleButtonPushed, true);
            app.doubleButton.Text = 'double图像转换';
            app.uint8Label = uilabel(app.Panel_2);
            app.uint8Label.Text = '转换为uint8格式';
            app.doubleLabel = uilabel(app.Panel_2);
            app.doubleLabel.Text = '转换为double格式';
            app.fake_rgb_generate = uibutton(app.Panel_2, 'push');
            app.fake_rgb_generate.ButtonPushedFcn = createCallbackFcn(app, @fake_rgb_generateButtonPushed, true);
            app.fake_rgb_generate.Text = '伪彩色图像处理';
            app.Label_19 = uilabel(app.Panel_2);
            app.Label_19.Text = '伪彩色图像处理模块';
            app.Label_20 = uilabel(app.Panel_2);
            app.Label_20.Text = '处理格式';
            app.DropDown_choose_generate = uidropdown(app.Panel_2);
            app.DropDown_choose_generate.Items = {'密度分割法', '灰度级变换法', '频率滤波法'};
            app.DropDown_choose_generate.DropDownOpeningFcn = createCallbackFcn(app, @DropDown_choose_generateOpening, true);
            app.DropDown_choose_generate.ClickedFcn = createCallbackFcn(app, @DropDown_choose_generateClicked, true);
            app.DropDown_choose_generate.Value = '密度分割法';
            app.Panel_3 = uipanel(app.Tab);
            app.Panel_3.Title = '图像效果添加';
            app.Label = uilabel(app.Panel_3);
            app.Label.Text = '添加噪声';
            app.choosenoice = uidropdown(app.Panel_3);
            app.choosenoice.Items = {'高斯噪声', '泊松噪声', '椒盐噪声', '斑点噪声'};
            app.choosenoice.ValueChangedFcn = createCallbackFcn(app, @choosenoiceValueChanged, true);
            app.choosenoice.Value = '高斯噪声';
            app.Label_5 = uilabel(app.Panel_3);
            app.Label_5.Text = '方差/噪声密度';
            app.variance = uislider(app.Panel_3);
            app.variance.MajorTicks = [0 20 40 60 80 100];
            app.variance.MajorTickLabels = {'0', '0.2', '0.4', '0.6', '0.8', '1'};
            app.variance.ValueChangingFcn = createCallbackFcn(app, @varianceValueChanging, true);
            app.Panel_4 = uipanel(app.Tab);
            app.Panel_4.Title = '图像简单处理';
            app.Button_image_rotate = uibutton(app.Panel_4, 'push');
            app.Button_image_rotate.ButtonPushedFcn = createCallbackFcn(app, @Button_image_rotatePushed, true);
            app.Button_image_rotate.Text = '图像旋转';
            app.button_rotate_shuiping = uibutton(app.Panel_4, 'push');
            app.button_rotate_shuiping.ButtonPushedFcn = createCallbackFcn(app, @button_rotate_shuipingPushed, true);
            app.button_rotate_shuiping.Text = '图像水平翻转';
            app.Button_rotate_shuzhi = uibutton(app.Panel_4, 'push');
            app.Button_rotate_shuzhi.ButtonPushedFcn = createCallbackFcn(app, @Button_rotate_shuzhiPushed, true);
            app.Button_rotate_shuzhi.Text = '图像竖直翻转';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '图像增强';
            app.Panel_6 = uipanel(app.Tab_2);
            app.Panel_6.Title = '图像增强编辑页面';
            app.TabGroup2 = uitabgroup(app.Panel_6);
            app.Tab_5 = uitab(app.TabGroup2);
            app.Tab_5.Title = '灰度图像增强';
            app.Label_7 = uilabel(app.Tab_5);
            app.Label_7.Text = '参数设置';
            app.Label_8 = uilabel(app.Tab_5);
            app.Label_8.Text = '输入范围';
            app.Label_9 = uilabel(app.Tab_5);
            app.Label_9.Text = '输出范围';
            app.GammaLabel = uilabel(app.Tab_5);
            app.GammaLabel.Text = 'Gamma值';
            app.lowin = uieditfield(app.Tab_5, 'numeric');
            app.lowin.Limits = [0 1];
            app.Label_10 = uilabel(app.Tab_5);
            app.Label_10.Text = '至';
            app.highin = uieditfield(app.Tab_5, 'numeric');
            app.highin.Limits = [0 1];
            app.lowout = uieditfield(app.Tab_5, 'numeric');
            app.lowout.Limits = [0 1];
            app.Label_11 = uilabel(app.Tab_5);
            app.Label_11.Text = '至';
            app.highout = uieditfield(app.Tab_5, 'numeric');
            app.highout.Limits = [0 1];
            app.gamma = uieditfield(app.Tab_5, 'numeric');
            app.chk_stable = uicheckbox(app.Tab_5);
            app.chk_stable.Text = '是否归一化';
            app.button_grayup = uibutton(app.Tab_5, 'push');
            app.button_grayup.ButtonPushedFcn = createCallbackFcn(app, @button_grayupButtonPushed, true);
            app.button_grayup.Text = '灰度图像增强';
            app.Tab_6 = uitab(app.TabGroup2);
            app.Tab_6.Title = '直方图图像增强（仅用于灰度）';
            app.UIAxes_hist_new = uiaxes(app.Tab_6);
            title(app.UIAxes_hist_new, '直方图')
            xlabel(app.UIAxes_hist_new, 'X')
            ylabel(app.UIAxes_hist_new, 'Y')
            zlabel(app.UIAxes_hist_new, 'Z')
            app.hist_stable_enhance = uibutton(app.Tab_6, 'push');
            app.hist_stable_enhance.ButtonPushedFcn = createCallbackFcn(app, @hist_stable_enhanceButtonPushed, true);
            app.hist_stable_enhance.Text = '直方图均衡化';
            app.Tab_7 = uitab(app.TabGroup2);
            app.Tab_7.Title = '空间滤波（仅用于灰度图像）';
            app.Label_13 = uilabel(app.Tab_7);
            app.Label_13.Text = '滤波器选择';
            app.fliter_w = uidropdown(app.Tab_7);
            app.fliter_w.Items = {'排序滤波器', '中值滤波器', '矩形平均滤波器', '高斯滤波器'};
            app.fliter_w.Value = '排序滤波器';
            app.start_filter = uibutton(app.Tab_7, 'push');
            app.start_filter.ButtonPushedFcn = createCallbackFcn(app, @start_filterButtonPushed, true);
            app.start_filter.Text = '开始空间滤波';
            app.Label_14 = uilabel(app.Tab_7);
            app.Label_14.Text = '边界填充格式';
            app.boundary_size = uidropdown(app.Tab_7);
            app.boundary_size.Items = {'repilcate', 'symmetric', 'circular'};
            app.boundary_size.Value = 'repilcate';
            app.Label_15 = uilabel(app.Tab_7);
            app.Label_15.Text = '滤波输出';
            app.size_out = uidropdown(app.Tab_7);
            app.size_out.Items = {'same', 'conv'};
            app.size_out.Value = 'same';
            app.Tab_8 = uitab(app.TabGroup2);
            app.Tab_8.Title = '频率域滤波图像增强(灰度)';
            app.Label_16 = uilabel(app.Tab_8);
            app.Label_16.Text = '请选择滤波器';
            app.DropDown_fliter_fre = uidropdown(app.Tab_8);
            app.DropDown_fliter_fre.Items = {'理想低通滤波器', 'ButterWorth低通滤波器', '高斯低通滤波器', '理想高通滤波器', 'ButterWorth高通滤波器', '高斯高通滤波器'};
            app.DropDown_fliter_fre.Value = '理想低通滤波器';
            app.Label_17 = uilabel(app.Tab_8);
            app.Label_17.Text = '截止频率';
            app.Slider_D0 = uislider(app.Tab_8);
            app.Slider_D0.MajorTickLabels = {'0', '10', '20', '30', '40', '50'};
            app.Label_18 = uilabel(app.Tab_8);
            app.Label_18.Text = '阶数改变';
            app.Slider_n = uislider(app.Tab_8);
            app.Slider_n.MajorTickLabels = {'0', '1', '2', '3', '4', '5'};
            app.enhance_fre = uibutton(app.Tab_8, 'push');
            app.enhance_fre.ButtonPushedFcn = createCallbackFcn(app, @enhance_freButtonPushed, true);
            app.enhance_fre.Text = '频率域滤波图像增强';
            app.Tab_11 = uitab(app.TabGroup2);
            app.Tab_11.Title = '运动模糊生成';
            app.Label_25 = uilabel(app.Tab_11);
            app.Label_25.Text = '模糊角度';
            app.Slider_theta = uislider(app.Tab_11);
            app.Slider_theta.MajorTicks = [0 15 32 49 66 83 100];
            app.Slider_theta.MajorTickLabels = {'0', '30', '60', '90', '120', '150', '180'};
            app.Label_26 = uilabel(app.Tab_11);
            app.Label_26.Text = '运动模糊生成';
            app.Button = uibutton(app.Tab_11, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '生成运动模糊的图像';
            app.Label_27 = uilabel(app.Tab_11);
            app.Label_27.Text = '移动像素点个数';
            app.EditField_move = uieditfield(app.Tab_11, 'numeric');
            app.EditField_move.Limits = [1 255];
            app.EditField_move.Value = 1;
            app.Tab_12 = uitab(app.TabGroup2);
            app.Tab_12.Title = '运动模糊复原';
            app.Button_moveback_2 = uibutton(app.Tab_12, 'push');
            app.Button_moveback_2.ButtonPushedFcn = createCallbackFcn(app, @Button_movebackPushed, true);
            app.Button_moveback_2.Text = '运动模糊还原';
            app.Label_32 = uilabel(app.Tab_12);
            app.Label_32.Text = '还原滤波器';
            app.DropDown_chooseback_move_3 = uidropdown(app.Tab_12);
            app.DropDown_chooseback_move_3.Items = {'逆滤波', '维纳滤波'};
            app.DropDown_chooseback_move_3.Value = '逆滤波';
            app.Label_33 = uilabel(app.Tab_12);
            app.Label_33.Text = '运动模糊复原';
            app.Label_34 = uilabel(app.Tab_12);
            app.Label_34.Text = '滤波半径';
            app.EditField_lvbor = uieditfield(app.Tab_12, 'text');
            app.EditField_lvbor.CharacterLimits = [0 255];
            app.EditField_lvbor.Value = '0';
            app.Label_35 = uilabel(app.Tab_12);
            app.Label_35.Text = '还原角度';
            app.Slider_theta_weina = uislider(app.Tab_12);
            app.Slider_theta_weina.MajorTicks = [0 15 32 49 66 83 100];
            app.Slider_theta_weina.MajorTickLabels = {'0', '30', '60', '90', '120', '150', '180'};
            app.Label_36 = uilabel(app.Tab_12);
            app.Label_36.Text = '逆滤波参数';
            app.Label_37 = uilabel(app.Tab_12);
            app.Label_37.Text = '维纳滤波参数';
            app.Label_38 = uilabel(app.Tab_12);
            app.Label_38.Text = '移动像素点个数';
            app.EditField_movexiangsudian = uieditfield(app.Tab_12, 'numeric');
            app.EditField_movexiangsudian.Limits = [2 Inf];
            app.EditField_movexiangsudian.Value = 2;
            app.Label_39 = uilabel(app.Tab_12);
            app.Label_39.Text = '信噪比';
            app.EditField_therate = uieditfield(app.Tab_12, 'numeric');
            app.EditField_therate.Limits = [0 1];
            app.RadonRadonTab = uitab(app.TabGroup2);
            app.RadonRadonTab.Title = 'Radon变换与反Radon变换';
            app.RadonButton = uibutton(app.RadonRadonTab, 'push');
            app.RadonButton.ButtonPushedFcn = createCallbackFcn(app, @RadonButtonPushed, true);
            app.RadonButton.Text = 'Radon变换';
            app.RadonLabel = uilabel(app.RadonRadonTab);
            app.RadonLabel.Text = 'Radon变换';
            app.RadonLabel_2 = uilabel(app.RadonRadonTab);
            app.RadonLabel_2.Text = '反Radon变换';
            app.RadonButtonback = uibutton(app.RadonRadonTab, 'push');
            app.RadonButtonback.ButtonPushedFcn = createCallbackFcn(app, @RadonButtonbackPushed, true);
            app.RadonButtonback.Text = '反Radon变换';
            app.Label_40 = uilabel(app.RadonRadonTab);
            app.Label_40.Text = '插值类型';
            app.DropDown_iradon_type = uidropdown(app.RadonRadonTab);
            app.DropDown_iradon_type.Items = {'linear', 'nearest', 'cubic', 'spline'};
            app.DropDown_iradon_type.Value = 'linear';
            app.Label_41 = uilabel(app.RadonRadonTab);
            app.Label_41.Text = '滤波器类型';
            app.DropDown_iradon_filter = uidropdown(app.RadonRadonTab);
            app.DropDown_iradon_filter.Items = {'Ram-Lak', 'Shepp-Logan', 'Cosine'};
            app.DropDown_iradon_filter.Value = 'Ram-Lak';
            app.TabGroup3 = uitabgroup(app.Tab_2);
            app.Tab_9 = uitab(app.TabGroup3);
            app.Tab_9.Title = '原图直方图生成器';
            app.UIAxes_hist = uiaxes(app.Tab_9);
            title(app.UIAxes_hist, '直方图')
            xlabel(app.UIAxes_hist, 'X')
            ylabel(app.UIAxes_hist, 'Y')
            zlabel(app.UIAxes_hist, 'Z')
            app.Label_12 = uilabel(app.Tab_9);
            app.Label_12.Text = '灰度级数设置';
            app.line_gray = uieditfield(app.Tab_9, 'numeric');
            app.line_gray.Limits = [0 Inf];
            app.line_gray.Value = 255;
            app.chk_stable_hist = uicheckbox(app.Tab_9);
            app.chk_stable_hist.Text = '是否归一化';
            app.button_generate_hist = uibutton(app.Tab_9, 'push');
            app.button_generate_hist.ButtonPushedFcn = createCallbackFcn(app, @button_generate_histButtonPushed, true);
            app.button_generate_hist.Text = '生成直方图';
            app.Tab_10 = uitab(app.TabGroup3);
            app.Tab_10.Title = '傅里叶变换频率生成器';
            app.UIAxes_frequency = uiaxes(app.Tab_10);
            title(app.UIAxes_frequency, '频率图')
            xlabel(app.UIAxes_frequency, 'X')
            ylabel(app.UIAxes_frequency, 'Y')
            zlabel(app.UIAxes_frequency, 'Z')
            app.Button_generate = uibutton(app.Tab_10, 'push');
            app.Button_generate.ButtonPushedFcn = createCallbackFcn(app, @Button_generatePushed, true);
            app.Button_generate.Text = '生成频率图';
            app.chk_change_center = uicheckbox(app.Tab_10);
            app.chk_change_center.Text = '原点放入左上角';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '图像压缩';
            app.Panel_7 = uipanel(app.Tab_3);
            app.Panel_7.Title = '霍夫曼编码压缩';
            app.button_huffman = uibutton(app.Panel_7, 'push');
            app.button_huffman.ButtonPushedFcn = createCallbackFcn(app, @button_huffmanButtonPushed, true);
            app.button_huffman.Text = '霍夫曼编码压缩';
            app.Label_42 = uilabel(app.Panel_7);
            app.Label_42.Text = '压缩信息';
            app.TextArea = uitextarea(app.Panel_7);
            app.Label_43 = uilabel(app.Panel_7);
            app.Label_43.Text = {'对图像进行霍夫曼编码压缩。'; '显示压缩比等基本信息'};
            app.Panel_10 = uipanel(app.Tab_3);
            app.Panel_10.Title = '预测编码压缩';
            app.Button_prediction = uibutton(app.Panel_10, 'push');
            app.Button_prediction.ButtonPushedFcn = createCallbackFcn(app, @Button_predictionPushed, true);
            app.Button_prediction.Text = '预测编码压缩';
            app.TextArea_2Label = uilabel(app.Panel_10);
            app.TextArea_2Label.Text = '压缩信息';
            app.TextArea2 = uitextarea(app.Panel_10);
            app.Label_44 = uilabel(app.Panel_10);
            app.Label_44.Text = {'对图像进行预测编码压缩。'; '显示压缩比等基本信息。'};
            app.back_all = uibutton(app.ver10Panel, 'push');
            app.back_all.ButtonPushedFcn = createCallbackFcn(app, @back_allButtonPushed, true);
            app.back_all.Icon = fullfile(pathToMLAPP, 'icons', '修改重置.png');
            app.back_all.Text = '重置所有操作';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end